classdef GUIforFEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportResultsButton             matlab.ui.control.Button
        tEditField                      matlab.ui.control.NumericEditField
        tEditFieldLabel                 matlab.ui.control.Label
        vEditField                      matlab.ui.control.NumericEditField
        vEditFieldLabel                 matlab.ui.control.Label
        EEditField                      matlab.ui.control.NumericEditField
        EEditFieldLabel                 matlab.ui.control.Label
        FtEditField                     matlab.ui.control.NumericEditField
        FtEditFieldLabel                matlab.ui.control.Label
        FcEditField                     matlab.ui.control.NumericEditField
        FcEditFieldLabel                matlab.ui.control.Label
        ResultDropDown                  matlab.ui.control.DropDown
        ResultTypeDropDownLabel         matlab.ui.control.Label
        SelectBoundaryConditionsButton  matlab.ui.control.Button
        MeshSlider                      matlab.ui.control.Slider
        MeshSliderLabel                 matlab.ui.control.Label
        GeometryDropDown                matlab.ui.control.DropDown
        GeometryDropDownLabel           matlab.ui.control.Label
        DrawGeometryButton              matlab.ui.control.Button
        ShowResultsButton               matlab.ui.control.Button
        DisplayDeformedStructureButton  matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        ShowMeshInfoButton              matlab.ui.control.Button
        GenerateMeshButton              matlab.ui.control.Button
        LoadModelButton                 matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)

        n_element
        n_nodes
        E
        ncon
        X
        Y
        NDU
        dzero
        F
        v
        t
        K
        U
        Sx
        Sy
        Sxy
        Ex
        Ey
        Gxy
        Fc
        Ft

        geometryMode   % 'excel' or 'manual'
        selectedNodes  % for BC selection
        meshDensity    % scalar

    end

    methods (Access = private)

        function ResultDropDownValueChanged(app)

            switch app.ResultDropDown.Value

                case 'Deformation'
                    plot_deformed(app.UIAxes_2, ...
                        app.ncon, app.X, app.Y, ...
                        app.U, app.F, app.dzero);

                case 'Sx Stress'
                    plot_contour(app.UIAxes_2, ...
                        app.ncon, app.X, app.Y, ...
                        app.Sx, 'Sx Stress');

                case 'Sy Stress'
                    plot_contour(app.UIAxes_2, ...
                        app.ncon, app.X, app.Y, ...
                        app.Sy, 'Sy Stress');

                case 'Sxy Stress'
                    plot_contour(app.UIAxes_2, ...
                        app.ncon, app.X, app.Y, ...
                        app.Sxy, 'Sxy Stress');
            end

        end

        function GenerateMesh(app)

            if strcmp(app.GeometryDropDown.Value,'Manual')

                if isempty(app.manualX)
                    error('No manual geometry defined');
                end

                tri = delaunay(app.manualX, app.manualY);

                app.ncon = tri;
                app.X = app.manualX;
                app.Y = app.manualY;

                app.n_nodes = length(app.X);
                app.n_element = size(tri,1);

            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)

            app.geometryMode = 'excel';
            app.meshDensity = app.MeshSlider.Value;
            app.selectedNodes = [];

            app.GeometryDropDown.Items = {'Excel','Manual'};
            app.GeometryDropDown.Value = 'Excel';

            app.ResultDropDown.Items = ...
                {'Deformation','Sx Stress','Sy Stress','Sxy Stress'};

            app.ResultDropDown.Value = 'Deformation';

        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)

            [app.ncon, app.X, app.Y, app.F, app.dzero, ...
                app.n_nodes, app.n_element, ...
                app.E, app.v, app.t, app.NDU, app.Fc, app.Ft] = build_tool_data();
                app.FcEditField.Value = app.Fc;
                app.FtEditField.Value = app.Ft;
                app.EEditField.Value = app.E;
                app.vEditField.Value = app.v;
                app.tEditField.Value = app.t;

            uialert(app.UIFigure,'Data loaded successfully','Success');

        end

        % Button pushed function: GenerateMeshButton
        function GenerateMeshButtonPushed(app, event)

            cla(app.UIAxes)

            patch(app.UIAxes,'Faces',app.ncon,'Vertices',[app.X app.Y], ...
                'FaceColor','none','EdgeColor','k');

            axis(app.UIAxes,'equal')
            grid(app.UIAxes,'on')
            title(app.UIAxes,'Generated Mesh')

      
        end

        % Button pushed function: ShowMeshInfoButton
        function ShowMeshInfoButtonPushed(app, event)

            if isempty(app.ncon)
                uialert(app.UIFigure,'Load or create a model first.','No Model');
                return;
            end

            msg = sprintf([ ...
                'Nodes: %d\n' ...
                'Elements: %d\n' ...
                'DOFs: %d\n' ...
                'Young''s Modulus: %.3e Pa\n' ...
                'Poisson Ratio: %.2f'], ...
                app.n_nodes, ...
                app.n_element, ...
                2*app.n_nodes, ...
                app.E, ...
                app.v);

            uialert(app.UIFigure,msg,'Mesh Information');

        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)

            if isempty(app.ncon)
                uialert(app.UIFigure,'Load or create a model first.','No Model');
                return;
            end

            [app.U, app.Sx, app.Sy, app.Sxy, app.Ex, app.Ey, app.Gxy] = run_FEA( ...
                app.ncon, app.X, app.Y, ...
                app.E, app.v, app.t, ...
                app.F, app.dzero, app.NDU);

            uialert(app.UIFigure,'Analysis Complete','Solved');
            msg = sprintf('Max U = %.3e m\nMax Sx = %.3e Pa', ...
                max(abs(app.U)), max(abs(app.Sx)));
            uialert(app.UIFigure,msg,'Analysis Complete');

            ResultDropDownValueChanged(app)

        end

        % Button pushed function: DisplayDeformedStructureButton
        function DisplayDeformedStructureButtonPushed(app, event)

            plot_deformed(app.UIAxes_2, ...
                app.ncon, app.X, app.Y, ...
                app.U, app.F, app.dzero);

        end

        % Button pushed function: ShowResultsButton
        function ShowResultsButtonPushed(app, event)

                ResultDropDownValueChanged(app);

        end

        % Button pushed function: DrawGeometryButton
        function DrawGeometryButtonPushed(app, event)
            cla(app.UIAxes);
            hold(app.UIAxes,'on');

            title(app.UIAxes,'Click nodes. Press Enter when done');

            [x,y] = ginput;

            app.manualX = x;
            app.manualY = y;

            % Generate mesh immediately
            tri = delaunay(x,y);

            app.ncon = tri;
            app.X = x;
            app.Y = y;
            app.n_nodes = length(x);
            app.n_element = size(tri,1);

            % Plot mesh
            triplot(tri,x,y,'k','Parent',app.UIAxes);
            plot(app.UIAxes,x,y,'ro');

            %triangulate with delaunay(x,y)
            %assign to app.ncon

        end

        % Button pushed function: SelectBoundaryConditionsButton
        function SelectBoundaryConditionsButtonPushed(app, event)
            title(app.UIAxes,'Click nodes to fix');

            [x_click,y_click] = ginput;

            selected = [];

            for k = 1:length(x_click)
                dist = sqrt((app.X - x_click(k)).^2 + (app.Y - y_click(k)).^2);
                [~, idx] = min(dist);
                selected(end+1) = idx;
            end

            app.selectedNodes = unique(selected);

            % Visual feedback
            scatter(app.UIAxes, app.X(app.selectedNodes), ...
                app.Y(app.selectedNodes), 80, 'filled','r');

        end

        % Value changed function: ResultDropDown
        function ResultDropDownValueChanged2(app, event)
            if ~isempty(app.U)
                ResultDropDownValueChanged(app);
            end
        end

        % Value changed function: EEditField
        function EEditFieldValueChanged(app, event)
            value = app.EEditField.Value;

        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            export_data();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tool Geometry & Deformed Shape')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Contour Plot')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.LoadModelButton = uibutton(app.UIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.GenerateMeshButton = uibutton(app.UIFigure, 'push');
            app.GenerateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshButtonPushed, true);
            app.GenerateMeshButton.Text = 'Generate Mesh';
            app.ShowMeshInfoButton = uibutton(app.UIFigure, 'push');
            app.ShowMeshInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMeshInfoButtonPushed, true);
            app.ShowMeshInfoButton.Text = 'Show Mesh Info';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.DisplayDeformedStructureButton = uibutton(app.UIFigure, 'push');
            app.DisplayDeformedStructureButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDeformedStructureButtonPushed, true);
            app.DisplayDeformedStructureButton.Text = 'Display Deformed Structure';
            app.ShowResultsButton = uibutton(app.UIFigure, 'push');
            app.ShowResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultsButtonPushed, true);
            app.ShowResultsButton.Text = 'Show Results';
            app.DrawGeometryButton = uibutton(app.UIFigure, 'push');
            app.DrawGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @DrawGeometryButtonPushed, true);
            app.DrawGeometryButton.Text = 'Draw Geometry';
            app.GeometryDropDownLabel = uilabel(app.UIFigure);
            app.GeometryDropDownLabel.Text = 'Geometry Drop Down';
            app.GeometryDropDown = uidropdown(app.UIFigure);
            app.MeshSliderLabel = uilabel(app.UIFigure);
            app.MeshSliderLabel.Text = 'Mesh Slider';
            app.MeshSlider = uislider(app.UIFigure);
            app.SelectBoundaryConditionsButton = uibutton(app.UIFigure, 'push');
            app.SelectBoundaryConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBoundaryConditionsButtonPushed, true);
            app.SelectBoundaryConditionsButton.Text = 'Select Boundary Conditions';
            app.ResultTypeDropDownLabel = uilabel(app.UIFigure);
            app.ResultTypeDropDownLabel.Text = 'Result Type';
            app.ResultDropDown = uidropdown(app.UIFigure);
            app.ResultDropDown.Items = {'Deformation', 'Sx Stress', 'Sy Stress', 'Sxy Stress'};
            app.ResultDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultDropDownValueChanged2, true);
            app.ResultDropDown.Value = 'Deformation';
            app.FcEditFieldLabel = uilabel(app.UIFigure);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.UIFigure, 'numeric');
            app.FtEditFieldLabel = uilabel(app.UIFigure);
            app.FtEditFieldLabel.Text = 'Ft';
            app.FtEditField = uieditfield(app.UIFigure, 'numeric');
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.UIFigure, 'numeric');
            app.EEditField.ValueChangedFcn = createCallbackFcn(app, @EEditFieldValueChanged, true);
            app.vEditFieldLabel = uilabel(app.UIFigure);
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExportResultsButton = uibutton(app.UIFigure, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIforFEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end